classdef Meta_Terminal_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MetaTerminal                 matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HomeTab                      matlab.ui.container.Tab
        statsButton                  matlab.ui.control.Button
        threadsButton                matlab.ui.control.Button
        memButton                    matlab.ui.control.Button
        systimeButton                matlab.ui.control.Button
        helloButton                  matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
        DisconnectButton             matlab.ui.control.Button
        SendText                     matlab.ui.control.EditField
        SendButton                   matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        OutputTextArea               matlab.ui.control.TextArea
        PortDropDownLabel            matlab.ui.control.Label
        PortDropDown                 matlab.ui.control.DropDown
        GimbalTab                    matlab.ui.container.Tab
        PIDAdjustmentPanel           matlab.ui.container.Panel
        label_Kp                     matlab.ui.control.Label
        label_Ki                     matlab.ui.control.Label
        label_Kd                     matlab.ui.control.Label
        label_i_limit                matlab.ui.control.Label
        label_out_limit              matlab.ui.control.Label
        PIDEchoButton                matlab.ui.control.Button
        KpSpinner                    matlab.ui.control.Spinner
        KiSpinner                    matlab.ui.control.Spinner
        KdSpinner                    matlab.ui.control.Spinner
        PIDSetButton                 matlab.ui.control.Button
        i_limitEdit                  matlab.ui.control.NumericEditField
        out_limitEdit                matlab.ui.control.NumericEditField
        MainAxes                     matlab.ui.control.UIAxes
        Vice1_Axes                   matlab.ui.control.UIAxes
        MainAxesLabel                matlab.ui.control.Label
        label_Current                matlab.ui.control.Label
        label_Target                 matlab.ui.control.Label
        MainCurrentLabel             matlab.ui.control.Label
        MainTargetLabel              matlab.ui.control.Label
        label_Current_2              matlab.ui.control.Label
        label_Target_2               matlab.ui.control.Label
        Vice1_CurrentLabel           matlab.ui.control.Label
        Vice1_TargetLabel            matlab.ui.control.Label
        Vice1_AxesLabel              matlab.ui.control.Label
        MotorButtonGroup             matlab.ui.container.ButtonGroup
        MotorYawCheck                matlab.ui.control.RadioButton
        MotorPitchCheck              matlab.ui.control.RadioButton
        SetFrontAngleButton          matlab.ui.control.Button
        MotorEnabledButton           matlab.ui.control.StateButton
        MainValueButtonGroup         matlab.ui.container.ButtonGroup
        MVCurrentCheck               matlab.ui.control.RadioButton
        MVVelocityCheck              matlab.ui.control.RadioButton
        MVAngleCheck                 matlab.ui.control.RadioButton
        Vice2_Axes                   matlab.ui.control.UIAxes
        label_Current_3              matlab.ui.control.Label
        label_Targt_3                matlab.ui.control.Label
        Vice2_CurrentLabel           matlab.ui.control.Label
        Vice2_TargetLabel            matlab.ui.control.Label
        Vice2_AxesLabel              matlab.ui.control.Label
        HistoryPanel                 matlab.ui.container.Panel
        HistoryListBox               matlab.ui.control.ListBox
        HistoryDeleteSelectedButton  matlab.ui.control.Button
        HistorySaveAllButton         matlab.ui.control.Button
        HistoryLoadSelectedButton    matlab.ui.control.Button
        DisplayButtonGroup           matlab.ui.container.ButtonGroup
        DisplayContinuousButton      matlab.ui.control.ToggleButton
        DisplayFreezeButton          matlab.ui.control.ToggleButton
        DisplayAutoButton            matlab.ui.control.ToggleButton
        DisplayRecentCheck           matlab.ui.control.CheckBox
        DisplayPeriod                matlab.ui.control.Spinner
        label_s                      matlab.ui.control.Label
        PauseLampLabel               matlab.ui.control.Label
        PauseLamp                    matlab.ui.control.Lamp
        SignalLampLabel              matlab.ui.control.Label
        SignalLamp                   matlab.ui.control.Lamp
        RightValueinMainAxesLabel    matlab.ui.control.Label
        RightValueDropDown           matlab.ui.control.DropDown
        DataOperationsPanel          matlab.ui.container.Panel
        DataExportButton             matlab.ui.control.Button
        DataClearButton              matlab.ui.control.Button
        TargetsPanel                 matlab.ui.container.Panel
        TargetsListBox               matlab.ui.control.ListBox
        RunforSpinnerLabel           matlab.ui.control.Label
        TargetsRunCountSpinner       matlab.ui.control.Spinner
        TargetsRunButton             matlab.ui.control.Button
        TargetEditFieldLabel         matlab.ui.control.Label
        TargetsAddEdit               matlab.ui.control.NumericEditField
        TargetsAddButton             matlab.ui.control.Button
        TargetsDeleteSelectedButton  matlab.ui.control.Button
        TimeforeachSpinnerLabel      matlab.ui.control.Label
        TargetsTimeForEachSpinner    matlab.ui.control.Spinner
        label_s_2                    matlab.ui.control.Label
        RunLamp                      matlab.ui.control.Lamp
        StatusLabel                  matlab.ui.control.Label
        label_Difference             matlab.ui.control.Label
        MainDifferenceLabel          matlab.ui.control.Label
        CurrentRunGaugeLabel         matlab.ui.control.Label
        CurrentRunGauge              matlab.ui.control.Gauge
        AnalysisDataPanel            matlab.ui.container.Panel
        AnalysisListBox              matlab.ui.control.ListBox
        AnalysisDeleteButton         matlab.ui.control.Button
        AnalysisClearButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        % MATLAB index - 1 = control program index
        
        cur_motor % current motor index. Yaw = 1, Pitch = 2
        cur_mv % current main value index. Angle = 1, Velocity = 2, Current = 3
        % current_pid is the same as cur_mv (-1 for control program index), except that there is no pid for current
        
        cur_right_value % right value corresponding to index of data_value
        
        mv_plot_index % maps from main value index to three value index for main axes and two vice axes
        % mv_plot_index * 2 - 1 = index of acutal values in data_value
        % mv_plot_index * 2     = index of target values in data_value
        
        mv_title % maps from main value index to title
        mv_cmd_suffix % maps from main value index to command surfix (g_set%s)
        mv_pid_name % maps from main value index to pid name
        
        data_time % {acutal_angle, target_angle, actual_velocity, target_velocity, actual_current, target current}
        last_recevied_time % time for auto mode. Set to 0 as clear data
        base_time % start time for a run (consist of several targets) for Auto display mode
        data_value % {actual_? target_? * 3 } 6 cells of double array. Only data for current motor is stored
        
        update_counter % counter to draw plot, calc analysis values and switch lamps
        
        targets % {motor_count}{main_value_count} cells of double array of target values
        
        scom  % serial port
        
        pid_params % local storage of PID params. {motor_count}{main_value_count} cells of double array of params
        
        run_status % 0: not running, 1: runninng
        
        run_cur_index % current target index in app.TargetsListBox
        run_cur_start_time % current target start time
        
        run_remain_targets
        
        pid_history % local storage of history of PID params. {motor_count}{main_value_count} cells of array of array of params
        
    end
    
    methods (Access = private)
        
        % Helper functions to set status bar
        function setStatusNormal(app, text)
            app.StatusLabel.Text = text;
            app.StatusLabel.BackgroundColor = 'none';
        end
        function setStatusOK(app, text)
            app.StatusLabel.Text = text;
            app.StatusLabel.BackgroundColor = [0.39,0.83,0.07]; % green
        end
        function setStatusWarning(app, text)
            app.StatusLabel.Text = text;
            app.StatusLabel.BackgroundColor = [1.00,0.41,0.16]; % red
        end
        
        % Helper function to plot variables to axes and set labels
        function plotToAxes(~, target_axes, time_values, actual_values, target_values, time_range, actual_label, target_label)
            
            cla(target_axes, "reset");
            plot(time_values, actual_values, ".-", "linewidth", 2, "Parent", target_axes);
            hold(target_axes, 'on');
            plot(time_values, target_values, ".--", "linewidth", 2, "Parent", target_axes);
            target_axes.XLim = time_range;
            
            if nargin > 6
                actual_label.Text = num2str(actual_values(end));
                target_label.Text = num2str(target_values(end));
            end
            
        end
        
        % Update plot and labels (and app.PauseLamp for Auto mode)
        function updatePlot(app)
            
            if app.DisplayAutoButton.Value == 1 % Auto mode
                % Display time range: start run - end run
                time_range = [app.base_time app.base_time+app.TargetsRunCountSpinner.Value*app.TargetsTimeForEachSpinner.Value*1000];
            elseif app.DisplayContinuousButton.Value == 1 % Continuous mode
                if app.DisplayRecentCheck.Value == 1 % Only show the latest (app.DisplayPeriod.Value) seconds
                    time_range = [app.data_time(end)-app.DisplayPeriod.Value*1000 app.data_time(end)];
                else
                    time_range = [-inf inf]; % auto
                end
            else
                return;
            end
            
            
            % Plot
            yyaxis(app.MainAxes, "left");
            plotToAxes(app, app.MainAxes, ...
                app.data_time, ...
                app.data_value{app.mv_plot_index{app.cur_mv}{1} * 2 - 1}, ...
                app.data_value{app.mv_plot_index{app.cur_mv}{1} * 2}, ...
                time_range, app.MainCurrentLabel, app.MainTargetLabel);
            if app.cur_right_value ~= 0
                yyaxis(app.MainAxes, "right");
                plot(app.data_time, app.data_value{app.cur_right_value}, ...
                    ".-", "color", [0.93,0.69,0.13], "linewidth", 1, "Parent", app.MainAxes);
            end
            plotToAxes(app, app.Vice1_Axes, ...
                app.data_time, ...
                app.data_value{app.mv_plot_index{app.cur_mv}{2} * 2 - 1}, ...
                app.data_value{app.mv_plot_index{app.cur_mv}{2} * 2}, ...
                time_range, app.Vice1_CurrentLabel, app.Vice1_TargetLabel);
            plotToAxes(app, app.Vice2_Axes, ...
                app.data_time, ...
                app.data_value{app.mv_plot_index{app.cur_mv}{3} * 2 - 1}, ...
                app.data_value{app.mv_plot_index{app.cur_mv}{3} * 2}, ...
                time_range, app.Vice2_CurrentLabel, app.Vice2_TargetLabel);
            
            % Set Difference Label
            app.MainDifferenceLabel.Text = num2str(app.data_value{app.mv_plot_index{app.cur_mv}{1} * 2}(end) ...
                - app.data_value{app.mv_plot_index{app.cur_mv}{1} * 2 - 1}(end));
        end
        
        % Print message to output
        function printToOutput(app, str)
            app.OutputTextArea.Value = [app.OutputTextArea.Value; str];
        end
        
        % Send string through serial port
        function sendString(app, str)
            fprintf("Sending: %s\n", str);
            fprintf(app.scom, "%s\n", str);
        end
        
        % Helper function to load targets to app.TargetsListBox
        function loadTargets(app)
            app.TargetsListBox.Items = app.targets{app.cur_motor}{app.cur_mv};
        end
        
        % Helper function to load locally stored PID params to edit fields
        function loadPIDParams(app)
            app.KpSpinner.Value = app.pid_params{app.cur_motor}{app.cur_mv}(1);
            app.KiSpinner.Value = app.pid_params{app.cur_motor}{app.cur_mv}(2);
            app.KdSpinner.Value = app.pid_params{app.cur_motor}{app.cur_mv}(3);
            app.i_limitEdit.Value = app.pid_params{app.cur_motor}{app.cur_mv}(4);
            app.out_limitEdit.Value = app.pid_params{app.cur_motor}{app.cur_mv}(5);
        end
        
        % Helper function to load local PID params history to app.HistoryListBox
        function loadHistory(app)
            list = "";
            
            params_list = app.pid_history{app.cur_motor}{app.cur_mv};
            
            % We can't assign string to the end of an empty array (which is not string array)
            for k=1:length(params_list)
                s = sprintf("%.4f %.4f %.4f %.4f %.4f", params_list{k}(1), params_list{k}(2), params_list{k}(3), params_list{k}(4), params_list{k}(5));
                if list == ""
                    list = s;
                else
                    list(end+1) = s;
                end
            end
            app.HistoryListBox.Items = list;
        end
        
        % Helper function to set app.run_status and enable UI
        function run_set_status(app, value)
            app.run_status = value;
            if value == 0
                app.RunLamp.Color = [0.80,0.80,0.80];
            else
                app.RunLamp.Color = [0.00,1.00,0.00];
            end
            app.TargetsAddButton.Enable = (value == 0);
            app.TargetsDeleteSelectedButton.Enable = (value == 0);
            app.TargetsListBox.Enable = (value == 0);
            app.TargetsRunCountSpinner.Enable = (value == 0);
            app.TargetsTimeForEachSpinner.Enable = (value == 0);
            app.TargetsRunButton.Enable = (value == 0);
            app.DisplayContinuousButton.Enable = (value == 0);
            app.DisplayFreezeButton.Enable = (value == 0);
            app.DisplayAutoButton.Enable = (value == 0);
            app.PIDSetButton.Enable = (value == 0);
            app.AnalysisClearButton.Enable = (value == 0);
            app.AnalysisDeleteButton.Enable = (value == 0);
            
            if value == 0
                app.PauseLamp.Color = [0.80,0.80,0.80]; % grey
                app.PauseLampLabel.Text = "Pause";
            else
                app.PauseLamp.Color = [0.00,1.00,0.00]; % green
                app.PauseLampLabel.Text = "Updating";
            end
        end
        
        % Start a run of (count) targets starting from (index)
        function run_start_sequence(app, index, count)
            
            if app.run_status ~= 0
                app.run_stop();
            end
            
            if app.last_recevied_time == 0
                app.setStatusWarning("Please start receiving first");
                return;
            end
            
            app.base_time = 0; % set start time as last_received_time
            app.run_cur_index = index;
            app.run_remain_targets = count - 1;
            
            if app.DisplayAutoButton.Value == 1 % Auto mode
                ClearDataButtonPushed(app, 0);
            end
            
            app.AnalysisListBox.Items = ["" app.AnalysisListBox.Items]; % add a new line at thhe front
            
            app.run_start(); % set target first, but not enable yet
            
            app.run_set_status(1); % set run status first, or failure feedback may come too fast.
            
            % Send enable and set interface
            app.MotorEnabledButton.Value = 1;
            app.send_motor_enable(1);
        end
        
        % Start one target. No control for motor enabled or run_status
        function run_start(app)
            
            % Send target value
            target = str2double(app.TargetsListBox.Items(app.run_cur_index));
            if app.cur_motor == 1
                yaw_target = target;
                pitch_target = 0;
            else
                yaw_target = 0;
                pitch_target = target;
            end
            app.sendString(sprintf("g_set%s %f %f", app.mv_cmd_suffix{app.cur_mv}, yaw_target, pitch_target));
            
            app.run_cur_start_time = 0;
            
            app.setStatusNormal(sprintf("Start running for target %f", target));
        end
        
        % Helper function to check whether a target has reach its period.
        function run_check(app, time)
            
            if app.base_time == 0
                app.base_time = time;
            end
            
            if app.run_cur_start_time == 0
                app.run_cur_start_time = time;
            end
            
            if time >= app.run_cur_start_time + app.TargetsTimeForEachSpinner.Value * 1000 % current target has reached it maximum time
                
                app.CurrentRunGauge.Value = app.TargetsTimeForEachSpinner.Value;
                
                % No need to change yaw/pitch.enabled here
                
                if (app.run_remain_targets > 0)
                    app.run_cur_index = app.run_cur_index + 1;
                    if app.run_cur_index > length(app.TargetsListBox.Items)
                        app.run_cur_index = 1; % return to the start
                    end
                    app.TargetsListBox.Value = app.TargetsListBox.Items(app.run_cur_index);
                    app.run_remain_targets = app.run_remain_targets - 1;
                    app.run_start();
                else
                    app.run_stop();
                    app.setStatusOK("Run Complete.");
                    return;
                end
                
            end
            
            app.CurrentRunGauge.Value = (time - app.run_cur_start_time) / 1000.0;
            
        end
        
        % Stop runninng and disable motors
        function run_stop(app)
            app.run_set_status(0);
            
            % Send enable and set interface
            app.MotorEnabledButton.Value = 0;
            app.send_motor_enable(0);
            
            app.run_cur_index = 0;
            app.run_remain_targets = 0;
            app.base_time = 0;
        end
        
        % Helper function to send motor enabled
        function send_motor_enable(app, value)
            if app.cur_motor == 1
                yaw_target = value;
                pitch_target = 0;
            else
                yaw_target = 0;
                pitch_target = value;
            end
            app.sendString(sprintf("g_enable %d %d", yaw_target, pitch_target));
        end
        
        % Helper function to enable motor feedback
        function send_feedback_enable(app)
            yaw_target = (app.cur_motor == 1);
            pitch_target = (app.cur_motor ~= 1);
            app.sendString(sprintf("g_enable_fb %d %d", yaw_target, pitch_target));
        end
        
        % Calculate statistic values
        function calc_statistic_values(app)
            % Difference array
            dif = app.data_value{app.mv_plot_index{app.cur_mv}{1} * 2}...
                - app.data_value{app.mv_plot_index{app.cur_mv}{1} * 2 - 1}; % target - actual
            % Average absolute difference
            avg_abs_dif = mean(abs(dif));
            % Standard deviation
            std_dev = std(dif);
            app.AnalysisListBox.Items(1) = cellstr(sprintf("[%s] avg_diff = %.2f, std_dev = %.2f", datestr(now, "HH:MM:SS"), avg_abs_dif, std_dev));
        end
        
    end
    
    methods (Access = public)
        
        % Callback funtion for serial
        function s_rx_callback(app, ~, ~)
            
            str = fgetl(app.scom);
            p = strsplit(str, ",");
            header = char(p(1));
            if strlength(header) >=2 && header(1) == "!" % feedback data
                if header(2) == "g" % gimbal feedback
                    if header(3) == "y" % yaw
                        motor_id = 1;
                    elseif header(3) == "p" % pitch
                        motor_id = 2;
                    end
                    
                    if motor_id == app.cur_motor % only store data for current motor
                        
                        app.update_counter = app.update_counter + 1;
                        
                        if (app.DisplayAutoButton.Value ~= 1) || (app.run_status == 1) % Not Auto mode or is running
                            % Store data. Don't store data in Auto mode when it's not running
                            app.data_time(end+1) = str2double(char(p(2)));
                            
                            for i = 1:6
                                app.data_value{i}(end+1) = str2double(char(p(2 + i)));
                            end
                            
                            if app.update_counter >= 3
                                app.updatePlot();
                                app.calc_statistic_values();
                            end
                        end
                        
                        
                        app.last_recevied_time = str2double(char(p(2)));
                        
                        if app.update_counter >= 3
                            % Swap signal lamp color
                            if app.SignalLamp.Color(1) == 0.80 % is grey now
                                app.SignalLamp.Color = [0.00,1.00,0.00]; % green
                            else % is green now
                                app.SignalLamp.Color = [0.80,0.80,0.80]; % grey
                            end
                        end
                        
                        if app.run_status == 1
                            app.run_check(app.data_time(end));
                        end
                        
                        if app.update_counter >= 3
                            app.update_counter = 0;
                        end
                    end
                    
                elseif header(2) == "d" % disabled
                    
                    if header(3) == "y"
                        motor_name = "Yaw";
                    elseif header(3) == "p"
                        motor_name = "Pitch";
                    end
                    
                    if header(4) == "A"
                        problem = "max actual angle";
                    elseif header(4) == "a"
                        problem = "min actual angle";
                    elseif header(4) == "v"
                        problem = "max actual velocity";
                    elseif header(4) == "c"
                        problem = "max target current";
                    end
                    
                    message = sprintf("[WARNING] %s reached %s. Disabled.", motor_name, problem);
                    app.printToOutput(message);
                    if app.run_status == 1
                        app.run_stop();
                        app.AnalysisListBox.Items(1) = cellstr(app.AnalysisListBox.Items(1) + " (Halfdone)");
                    end
                    app.setStatusWarning(message);
                    
                elseif header(2) == "p"
                    if header(3) == "s" % parameters set
                        
                        % Save parameters
                        app.pid_params{app.cur_motor}{app.cur_mv}(1) = app.KpSpinner.Value;
                        app.pid_params{app.cur_motor}{app.cur_mv}(2) = app.KiSpinner.Value;
                        app.pid_params{app.cur_motor}{app.cur_mv}(3) = app.KdSpinner.Value;
                        app.pid_params{app.cur_motor}{app.cur_mv}(4) = app.i_limitEdit.Value;
                        app.pid_params{app.cur_motor}{app.cur_mv}(5) = app.out_limitEdit.Value;
                        app.pid_history{app.cur_motor}{app.cur_mv}{end+1} = app.pid_params{app.cur_motor}{app.cur_mv};
                        app.loadHistory()
                        
                        app.setStatusOK("PID parameters set and stored to history.");
                        
                    elseif header(3) == "e" % parameters set error
                        app.setStatusNormal("Failed to set PID parameters!");
                    end
                elseif header(2) == "f"
                    app.setStatusOK("Front angles set.");
                end
                
            else % normal data
                app.printToOutput (str);
            end
        end


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function Meta_Terminal_OpeningFcn(app)
            
            app.pid_params = {{zeros(5), zeros(5)}, {zeros(5), zeros(5)}};
            app.pid_history = {{{}, {}},{{}, {}}};
            app.mv_plot_index = {{1, 2, 3}, {2, 1, 3}, {3, 2, 1}};
            app.mv_title = {"Angle", "Velocity", "Current"};
            app.mv_cmd_suffix = {"_angle", "_v", ""};
            app.mv_pid_name = {{"yaw.angle_to_v_pid", "yaw.v_to_i_pid"}, {"pitch.angle_to_v_pid", "pitch.v_to_i_pid"}};
            
            ClearDataButtonPushed(app, 0);
            MotorButtonGroupSelectionChanged(app, 0); % set cur_mv
            MainValueButtonGroupSelectionChanged(app, 0); % set cur_motor
            TargetsTimeForEachSpinnerValueChanged(app, 0); % set CurrentRunnGauge interface
            DisplayButtonGroupSelectionChanged(app, 0);
            app.run_set_status(0);
            RightValueDropDownValueChanged(app, 0)
            
            app.targets = {{["100.0", "-100.0", "60.0", "-60.0"], ["60.0", "-60.0", "120.0", "-120.0"], ["1000", "-1000"]}, ...
                {["30.0", "-30.0"], ["60.0", "-60.0", "120.0", "-120.0"], ["1000", "-1000"]}}; % preset targets
            app.loadTargets();
        end

        % Button pushed function: ClearButton
        function ClearButton_Callback(app, event)
            app.OutputTextArea.Value = "";
        end

        % Button pushed function: ConnectButton
        function ConnectButton_Callback(app, event)
            delete(instrfind)  % close all open connection
            
            % Setup the serial port
            app.scom = serial(app.PortDropDown.Value);
            app.scom.BaudRate = 115200;
            app.scom.BytesAvailableFcnMode = "terminator";
            app.scom.Terminator = "CR/LF";
            app.scom.BytesAvailableFcn = @app.s_rx_callback;
            
            try
                fopen(app.scom);
            catch
                app.printToOutput ("[Error] Fail to Open Port. Check Connction.");
                fprintf("[Error] Fail to Open Port. Check Connction.\n");
                return;
            end
            
            app.send_feedback_enable();
            
            app.printToOutput ("[Connected]");
            fprintf("[Connected]\n");
        end

        % Button pushed function: SendButton
        function SendButton_Callback(app, event)
            sendString(app, app.SendText.Value);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButton_Callback(app, event)
            delete(instrfind);  % close all open connection
            app.printToOutput ("[Disconnected]");
            fprintf("[Disconnected]\n");
        end

        % Button pushed function: DataClearButton
        function ClearDataButtonPushed(app, event)
            
            % Clear the data
            app.data_time = [];
            app.data_value = {[], [], [], [], [], []};
            
            % Clear the image
            cla(app.MainAxes, "reset");
            cla(app.Vice1_Axes, "reset");
            cla(app.Vice2_Axes, "reset");
            
%             if event~= 0
%                 app.send_feedback_enable();
%             end
            
            app.last_recevied_time = 0;
            app.update_counter = 0;
        end

        % Button pushed function: statsButton
        function statsButtonPushed(app, event)
            app.sendString("stats");
        end

        % Button pushed function: threadsButton
        function threadsButtonPushed(app, event)
            app.sendString("threads");
        end

        % Button pushed function: memButton
        function memButtonPushed(app, event)
            app.sendString("mem");
        end

        % Button pushed function: systimeButton
        function systimeButtonPushed(app, event)
            app.sendString("systime");
        end

        % Button pushed function: SetFrontAngleButton
        function SetFrontAngleButtonPushed(app, event)
            app.setStatusNormal("Waiting for setting front angles...");
            app.sendString("g_fix");
        end

        % Button pushed function: TargetsAddButton
        function TargetsAddButtonPushed(app, event)
            app.targets{app.cur_motor}{app.cur_mv}(end+1) = app.TargetsAddEdit.Value;
            app.loadTargets();
            app.setStatusNormal("Targets stored");
        end

        % Close request function: MetaTerminal
        function MetaTerminalCloseRequest(app, event)
            delete(app)
            delete(instrfind);  % close all open connection
            fprintf("[Disconnect All]\n");
        end

        % Callback function
        function SetVelocityButtonPushed(app, event)
            app.sendString(sprintf("g_set_v %f %f", str2double(app.YawTargetVelocityEditField.Value), str2double(app.PitchTargetVelocityEditField.Value)));
        end

        % Callback function
        function SetAngleButtonPushed(app, event)
            app.sendString(sprintf("g_set_angle %f %f", str2double(app.YawTargetAngleEditField.Value), str2double(app.PitchTargetAngleEditField.Value)));
        end

        % Button pushed function: PIDEchoButton
        function PIDEchoButtonPushed(app, event)
            app.sendString("g_echo_params");
            app.setStatusNormal("Command g_echo_params sent. View in the Home tap.");
        end

        % Selection changed function: MotorButtonGroup
        function MotorButtonGroupSelectionChanged(app, event)
            % When app.MotorPitchCheck.Value = 1, pitch is selected, app.cur_motor = 2 = app.MotorPitchCheck.Value + 1, which also works for yaw
            app.cur_motor = (app.MotorPitchCheck.Value + 1);
            
            if event ~= 0 % avoid repeat initialize at the start of the terminal
                
                % Clear data, since data is only for current motor
                ClearDataButtonPushed(app, event)
                
                MainValueButtonGroupSelectionChanged(app, event);
                
                app.send_feedback_enable();
            end
        end

        % Button pushed function: PIDSetButton
        function PIDSetButtonPushed(app, event)
            
            if (app.KpSpinner.Value > 0 || app.KiSpinner.Value > 0 || app.KdSpinner.Value > 0) && app.out_limitEdit.Value == 0
                app.setStatusWarning("Notice out_limit == 0! Stop setting.");
                return;
            end
            
            if app.KiSpinner.Value > 0 && app.i_limitEdit.Value == 0
                app.setStatusWarning("Notice i_limit == 0! Stop setting.");
                return;
            end
            
            app.setStatusNormal("Waiting for PID parameter setting..."); % set status first, or feedback may come faster
            app.sendString(sprintf("g_set_params %d %d %f %f %f %f %f", ...
                app.cur_motor - 1, app.cur_mv - 1, ...
                app.KpSpinner.Value, app.KiSpinner.Value, app.KdSpinner.Value, ...
                app.i_limitEdit.Value, app.out_limitEdit.Value));
            
            % Local parameter storage will be performed when reveiving the setting success signnal
        end

        % Button pushed function: helloButton
        function helloButtonPushed(app, event)
            app.sendString("hello");
        end

        % Selection changed function: MainValueButtonGroup
        function MainValueButtonGroupSelectionChanged(app, event)
           
            if app.MVAngleCheck.Value
                app.cur_mv = 1; % angle
            elseif app.MVVelocityCheck.Value
                app.cur_mv = 2; % velocity
            elseif app.MVCurrentCheck.Value
                app.cur_mv = 3; % current;
            end
            
            if app.cur_mv <= 2 % angle or velocity
                
                % Load PID parameters
                app.PIDAdjustmentPanel.Title = app.mv_pid_name{app.cur_motor}{app.cur_mv};
                app.loadPIDParams()
                app.setStatusNormal("NOTICE: locally stored PID parameters may not equal to those in the program.");
                
                % Load History
                app.loadHistory()
                
            end
            
            % Update interface
            app.MainAxesLabel.Text = app.mv_title{app.mv_plot_index{app.cur_mv}{1}};
            app.Vice1_AxesLabel.Text = app.mv_title{app.mv_plot_index{app.cur_mv}{2}};
            app.Vice2_AxesLabel.Text = app.mv_title{app.mv_plot_index{app.cur_mv}{3}};
            app.HistoryPanel.Visible = ~app.MVCurrentCheck.Value;
            app.PIDAdjustmentPanel.Visible = ~app.MVCurrentCheck.Value;
            if ~isempty(app.data_time)
                updatePlot(app);
            end
            
            if event ~= 0 % avoid repeat initialize at the start of the terminal
                app.loadTargets();
            end
        end

        % Value changed function: TargetsTimeForEachSpinner
        function TargetsTimeForEachSpinnerValueChanged(app, event)
            value = app.TargetsTimeForEachSpinner.Value;
            app.CurrentRunGauge.Value = 0;
            app.CurrentRunGauge.Limits = [0 value];
        end

        % Button pushed function: TargetsDeleteSelectedButton
        function TargetsDeleteSelectedButtonPushed(app, event)
            [~, index] = ismember(app.TargetsListBox.Value, app.TargetsListBox.Items);
            if isempty(index)
               app.setStatusWarning("No target selected.");
               return;
            end
            app.targets{app.cur_motor}{app.cur_mv}(index) = [];
            app.setStatusNormal("Targets stored.");
            app.loadTargets();
        end

        % Button pushed function: TargetsRunButton
        function TargetsRunButtonPushed(app, event)
            [~, index] = ismember(app.TargetsListBox.Value, app.TargetsListBox.Items);
            if isempty(index)
                app.setStatusWarning("No target selected.");
                return;
            end
            app.setStatusNormal("Start running. Using enabled control to stop running.");
            app.run_start_sequence(index, app.TargetsRunCountSpinner.Value);
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            if event ~= 0
                ClearDataButtonPushed(app, 0);
            end
            app.DisplayRecentCheck.Visible = (app.DisplayContinuousButton.Value == 1);
            app.DisplayPeriod.Visible = (app.DisplayContinuousButton.Value == 1);
            app.label_s.Visible = (app.DisplayContinuousButton.Value == 1);
            app.PauseLamp.Visible = (app.DisplayAutoButton.Value == 1);
            app.PauseLampLabel.Visible = (app.DisplayAutoButton.Value == 1);
            app.SignalLamp.Visible = (app.DisplayAutoButton.Value == 1);
            app.SignalLampLabel.Visible = (app.DisplayAutoButton.Value == 1);
        end

        % Value changed function: MotorEnabledButton
        function MotorEnabledSwitchValueChanged(app, event)
            value = app.MotorEnabledButton.Value;
            app.send_motor_enable(value);
            if value == 0 && app.run_status == 1
                app.run_stop();
                app.setStatusWarning("Running is interupted!");
            end
        end

        % Button pushed function: HistoryDeleteSelectedButton
        function HistoryDeleteSelectedButtonPushed(app, event)
            [~, index] = ismember(app.HistoryListBox.Value, app.HistoryListBox.Items);
            if isempty(index)
               app.setStatusWarning("No history selected.");
               return;
            end
            app.pid_history{app.cur_motor}{app.cur_mv}(index) = []; % be careful of () not {}
            app.loadHistory()
        end

        % Button pushed function: HistoryLoadSelectedButton
        function HistoryLoadSelectedButtonPushed(app, event)
            [~, index] = ismember(app.HistoryListBox.Value, app.HistoryListBox.Items);
            if isempty(index)
               app.setStatusWarning("No history selected.");
               return;
            end
            app.pid_params{app.cur_motor}{app.cur_mv} = app.pid_history{app.cur_motor}{app.cur_mv}{index};
            app.loadPIDParams()
        end

        % Button pushed function: DataExportButton
        function DataExportButtonPushed(app, event)
            app.setStatusWarning("Not implemented yet.");
        end

        % Button pushed function: HistorySaveAllButton
        function HistorySaveAllButtonPushed(app, event)
            app.setStatusWarning("Not implemented yet.");
        end

        % Button pushed function: AnalysisDeleteButton
        function AnalysisDeleteButtonPushed(app, event)
            [~, index] = ismember(app.AnalysisListBox.Value, app.AnalysisListBox.Items);
            if isempty(index)
               app.setStatusWarning("No data selected.");
               return;
            end
            app.AnalysisListBox.Items(index) = [];
        end

        % Button pushed function: AnalysisClearButton
        function AnalysisClearButtonPushed(app, event)
            app.AnalysisListBox.Items = "";
        end

        % Value changed function: RightValueDropDown
        function RightValueDropDownValueChanged(app, event)
            value = app.RightValueDropDown.Value;
            if value == "Off"
                app.cur_right_value = 0;
            elseif value == "Actual Angle"
                app.cur_right_value = 1;
            elseif value == "Target Angle"
                app.cur_right_value = 2;
            elseif value == "Acutal Velocity"
                app.cur_right_value = 3;
            elseif value == "Target Velocity"
                app.cur_right_value = 4;
            elseif value == "Actual Current"
                app.cur_right_value = 5;
            elseif value == "Target Current"
                app.cur_right_value = 6;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MetaTerminal = uifigure;
            app.MetaTerminal.Name = 'Meta Terminal';
            app.MetaTerminal.CloseRequestFcn = createCallbackFcn(app, @MetaTerminalCloseRequest, true);
            app.TabGroup = uitabgroup(app.MetaTerminal);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.statsButton = uibutton(app.HomeTab, 'push');
            app.statsButton.ButtonPushedFcn = createCallbackFcn(app, @statsButtonPushed, true);
            app.statsButton.Text = 'stats';
            app.threadsButton = uibutton(app.HomeTab, 'push');
            app.threadsButton.ButtonPushedFcn = createCallbackFcn(app, @threadsButtonPushed, true);
            app.threadsButton.Text = 'threads';
            app.memButton = uibutton(app.HomeTab, 'push');
            app.memButton.ButtonPushedFcn = createCallbackFcn(app, @memButtonPushed, true);
            app.memButton.Text = 'mem';
            app.systimeButton = uibutton(app.HomeTab, 'push');
            app.systimeButton.ButtonPushedFcn = createCallbackFcn(app, @systimeButtonPushed, true);
            app.systimeButton.Text = 'systime';
            app.helloButton = uibutton(app.HomeTab, 'push');
            app.helloButton.ButtonPushedFcn = createCallbackFcn(app, @helloButtonPushed, true);
            app.helloButton.Text = 'hello';
            app.ConnectButton = uibutton(app.HomeTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_Callback, true);
            app.ConnectButton.Text = 'Connnect';
            app.DisconnectButton = uibutton(app.HomeTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButton_Callback, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.SendText = uieditfield(app.HomeTab, 'text');
            app.SendButton = uibutton(app.HomeTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButton_Callback, true);
            app.SendButton.Text = 'Send';
            app.ClearButton = uibutton(app.HomeTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_Callback, true);
            app.ClearButton.Text = 'Clear Output';
            app.OutputTextArea = uitextarea(app.HomeTab);
            app.PortDropDownLabel = uilabel(app.HomeTab);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.HomeTab);
            app.PortDropDown.Items = {'/dev/cu.SLAB_USBtoUART', '/dev/cu.HC-05-DevB', 'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.PortDropDown.Value = '/dev/cu.SLAB_USBtoUART';
            app.GimbalTab = uitab(app.TabGroup);
            app.GimbalTab.Title = 'Gimbal';
            app.PIDAdjustmentPanel = uipanel(app.GimbalTab);
            app.PIDAdjustmentPanel.Title = 'PID Adjustment';
            app.label_Kp = uilabel(app.PIDAdjustmentPanel);
            app.label_Kp.Text = 'Kp';
            app.label_Ki = uilabel(app.PIDAdjustmentPanel);
            app.label_Ki.Text = 'Ki';
            app.label_Kd = uilabel(app.PIDAdjustmentPanel);
            app.label_Kd.Text = 'Kd';
            app.label_i_limit = uilabel(app.PIDAdjustmentPanel);
            app.label_i_limit.Text = 'i_limit';
            app.label_out_limit = uilabel(app.PIDAdjustmentPanel);
            app.label_out_limit.Text = 'out_limit';
            app.PIDEchoButton = uibutton(app.PIDAdjustmentPanel, 'push');
            app.PIDEchoButton.ButtonPushedFcn = createCallbackFcn(app, @PIDEchoButtonPushed, true);
            app.PIDEchoButton.Text = 'Echo';
            app.KpSpinner = uispinner(app.PIDAdjustmentPanel);
            app.KpSpinner.Step = 0.01;
            app.KpSpinner.ValueDisplayFormat = '%g';
            app.KiSpinner = uispinner(app.PIDAdjustmentPanel);
            app.KiSpinner.Step = 0.01;
            app.KiSpinner.ValueDisplayFormat = '%g';
            app.KdSpinner = uispinner(app.PIDAdjustmentPanel);
            app.KdSpinner.Step = 0.01;
            app.KdSpinner.ValueDisplayFormat = '%g';
            app.PIDSetButton = uibutton(app.PIDAdjustmentPanel, 'push');
            app.PIDSetButton.ButtonPushedFcn = createCallbackFcn(app, @PIDSetButtonPushed, true);
            app.PIDSetButton.Text = 'Set';
            app.i_limitEdit = uieditfield(app.PIDAdjustmentPanel, 'numeric');
            app.i_limitEdit.ValueDisplayFormat = '%d';
            app.out_limitEdit = uieditfield(app.PIDAdjustmentPanel, 'numeric');
            app.out_limitEdit.ValueDisplayFormat = '%d';
            app.MainAxes = uiaxes(app.GimbalTab);
            app.Vice1_Axes = uiaxes(app.GimbalTab);
            app.MainAxesLabel = uilabel(app.GimbalTab);
            app.MainAxesLabel.Text = 'Current';
            app.label_Current = uilabel(app.GimbalTab);
            app.label_Current.Text = 'Current:';
            app.label_Target = uilabel(app.GimbalTab);
            app.label_Target.Text = 'Target:';
            app.MainCurrentLabel = uilabel(app.GimbalTab);
            app.MainCurrentLabel.Text = '0';
            app.MainTargetLabel = uilabel(app.GimbalTab);
            app.MainTargetLabel.Text = '0';
            app.label_Current_2 = uilabel(app.GimbalTab);
            app.label_Current_2.Text = 'Current:';
            app.label_Target_2 = uilabel(app.GimbalTab);
            app.label_Target_2.Text = 'Target:';
            app.Vice1_CurrentLabel = uilabel(app.GimbalTab);
            app.Vice1_CurrentLabel.Text = '0';
            app.Vice1_TargetLabel = uilabel(app.GimbalTab);
            app.Vice1_TargetLabel.Text = '0';
            app.Vice1_AxesLabel = uilabel(app.GimbalTab);
            app.Vice1_AxesLabel.Text = 'Velocity';
            app.MotorButtonGroup = uibuttongroup(app.GimbalTab);
            app.MotorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MotorButtonGroupSelectionChanged, true);
            app.MotorButtonGroup.Title = 'Motor';
            app.MotorYawCheck = uiradiobutton(app.MotorButtonGroup);
            app.MotorYawCheck.Text = 'Yaw';
            app.MotorYawCheck.Value = true;
            app.MotorPitchCheck = uiradiobutton(app.MotorButtonGroup);
            app.MotorPitchCheck.Text = 'Pitch';
            app.SetFrontAngleButton = uibutton(app.MotorButtonGroup, 'push');
            app.SetFrontAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SetFrontAngleButtonPushed, true);
            app.SetFrontAngleButton.Text = 'Set Front Angle';
            app.MotorEnabledButton = uibutton(app.MotorButtonGroup, 'state');
            app.MotorEnabledButton.ValueChangedFcn = createCallbackFcn(app, @MotorEnabledSwitchValueChanged, true);
            app.MotorEnabledButton.Text = 'Enabled';
            app.MainValueButtonGroup = uibuttongroup(app.GimbalTab);
            app.MainValueButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MainValueButtonGroupSelectionChanged, true);
            app.MainValueButtonGroup.Title = 'Main Value';
            app.MVCurrentCheck = uiradiobutton(app.MainValueButtonGroup);
            app.MVCurrentCheck.Text = 'Current';
            app.MVVelocityCheck = uiradiobutton(app.MainValueButtonGroup);
            app.MVVelocityCheck.Text = 'Velocity';
            app.MVVelocityCheck.Value = true;
            app.MVAngleCheck = uiradiobutton(app.MainValueButtonGroup);
            app.MVAngleCheck.Text = 'Angle';
            app.Vice2_Axes = uiaxes(app.GimbalTab);
            app.label_Current_3 = uilabel(app.GimbalTab);
            app.label_Current_3.Text = 'Current:';
            app.label_Targt_3 = uilabel(app.GimbalTab);
            app.label_Targt_3.Text = 'Target:';
            app.Vice2_CurrentLabel = uilabel(app.GimbalTab);
            app.Vice2_CurrentLabel.Text = '0';
            app.Vice2_TargetLabel = uilabel(app.GimbalTab);
            app.Vice2_TargetLabel.Text = '0';
            app.Vice2_AxesLabel = uilabel(app.GimbalTab);
            app.Vice2_AxesLabel.Text = 'Angle';
            app.HistoryPanel = uipanel(app.GimbalTab);
            app.HistoryPanel.Title = 'History';
            app.HistoryListBox = uilistbox(app.HistoryPanel);
            app.HistoryListBox.Items = {};
            app.HistoryListBox.Value = {};
            app.HistoryDeleteSelectedButton = uibutton(app.HistoryPanel, 'push');
            app.HistoryDeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @HistoryDeleteSelectedButtonPushed, true);
            app.HistoryDeleteSelectedButton.Text = 'Delete';
            app.HistorySaveAllButton = uibutton(app.HistoryPanel, 'push');
            app.HistorySaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @HistorySaveAllButtonPushed, true);
            app.HistorySaveAllButton.Text = 'Save All';
            app.HistoryLoadSelectedButton = uibutton(app.HistoryPanel, 'push');
            app.HistoryLoadSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @HistoryLoadSelectedButtonPushed, true);
            app.HistoryLoadSelectedButton.Text = 'Load';
            app.DisplayButtonGroup = uibuttongroup(app.GimbalTab);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.DisplayContinuousButton = uitogglebutton(app.DisplayButtonGroup);
            app.DisplayContinuousButton.Text = 'Continuous';
            app.DisplayContinuousButton.Value = true;
            app.DisplayFreezeButton = uitogglebutton(app.DisplayButtonGroup);
            app.DisplayFreezeButton.Text = 'Freeze';
            app.DisplayAutoButton = uitogglebutton(app.DisplayButtonGroup);
            app.DisplayAutoButton.Text = 'Auto';
            app.DisplayRecentCheck = uicheckbox(app.DisplayButtonGroup);
            app.DisplayRecentCheck.Text = 'Recent';
            app.DisplayRecentCheck.Value = true;
            app.DisplayPeriod = uispinner(app.DisplayButtonGroup);
            app.DisplayPeriod.Limits = [1 Inf];
            app.DisplayPeriod.Value = 20;
            app.label_s = uilabel(app.DisplayButtonGroup);
            app.label_s.Text = 's';
            app.PauseLampLabel = uilabel(app.DisplayButtonGroup);
            app.PauseLampLabel.Text = 'Pause';
            app.PauseLamp = uilamp(app.DisplayButtonGroup);
            app.SignalLampLabel = uilabel(app.DisplayButtonGroup);
            app.SignalLampLabel.Text = 'Receiving';
            app.SignalLamp = uilamp(app.DisplayButtonGroup);
            app.RightValueinMainAxesLabel = uilabel(app.DisplayButtonGroup);
            app.RightValueinMainAxesLabel.Text = {'Right Value in'; 'Main Axes'};
            app.RightValueDropDown = uidropdown(app.DisplayButtonGroup);
            app.RightValueDropDown.Items = {'Off', 'Actual Angle', 'Target Angle', 'Actual Velocity', 'Target Velocity', 'Actual Current', 'Target Current'};
            app.RightValueDropDown.ValueChangedFcn = createCallbackFcn(app, @RightValueDropDownValueChanged, true);
            app.RightValueDropDown.Value = 'Off';
            app.DataOperationsPanel = uipanel(app.GimbalTab);
            app.DataOperationsPanel.Title = 'Data Operations';
            app.DataExportButton = uibutton(app.DataOperationsPanel, 'push');
            app.DataExportButton.ButtonPushedFcn = createCallbackFcn(app, @DataExportButtonPushed, true);
            app.DataExportButton.Text = 'Export Data';
            app.DataClearButton = uibutton(app.DataOperationsPanel, 'push');
            app.DataClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.DataClearButton.Text = 'Clear Data';
            app.TargetsPanel = uipanel(app.GimbalTab);
            app.TargetsPanel.Title = 'Targets';
            app.TargetsListBox = uilistbox(app.TargetsPanel);
            app.TargetsListBox.Items = {};
            app.TargetsListBox.Value = {};
            app.RunforSpinnerLabel = uilabel(app.TargetsPanel);
            app.RunforSpinnerLabel.Text = 'Run for';
            app.TargetsRunCountSpinner = uispinner(app.TargetsPanel);
            app.TargetsRunCountSpinner.Limits = [1 Inf];
            app.TargetsRunCountSpinner.Value = 2;
            app.TargetsRunButton = uibutton(app.TargetsPanel, 'push');
            app.TargetsRunButton.ButtonPushedFcn = createCallbackFcn(app, @TargetsRunButtonPushed, true);
            app.TargetsRunButton.Text = 'Run';
            app.TargetEditFieldLabel = uilabel(app.TargetsPanel);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetsAddEdit = uieditfield(app.TargetsPanel, 'numeric');
            app.TargetsAddButton = uibutton(app.TargetsPanel, 'push');
            app.TargetsAddButton.ButtonPushedFcn = createCallbackFcn(app, @TargetsAddButtonPushed, true);
            app.TargetsAddButton.Text = 'Add Target';
            app.TargetsDeleteSelectedButton = uibutton(app.TargetsPanel, 'push');
            app.TargetsDeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @TargetsDeleteSelectedButtonPushed, true);
            app.TargetsDeleteSelectedButton.Text = 'Delete Selected';
            app.TimeforeachSpinnerLabel = uilabel(app.TargetsPanel);
            app.TimeforeachSpinnerLabel.Text = 'Time for each';
            app.TargetsTimeForEachSpinner = uispinner(app.TargetsPanel);
            app.TargetsTimeForEachSpinner.Limits = [1 Inf];
            app.TargetsTimeForEachSpinner.ValueChangedFcn = createCallbackFcn(app, @TargetsTimeForEachSpinnerValueChanged, true);
            app.TargetsTimeForEachSpinner.Value = 2;
            app.label_s_2 = uilabel(app.TargetsPanel);
            app.label_s_2.Text = 's';
            app.RunLamp = uilamp(app.TargetsPanel);
            app.StatusLabel = uilabel(app.GimbalTab);
            app.StatusLabel.Text = '';
            app.label_Difference = uilabel(app.GimbalTab);
            app.label_Difference.Text = 'Difference:';
            app.MainDifferenceLabel = uilabel(app.GimbalTab);
            app.MainDifferenceLabel.Text = '0';
            app.CurrentRunGaugeLabel = uilabel(app.GimbalTab);
            app.CurrentRunGaugeLabel.Text = 'Current Run';
            app.CurrentRunGauge = uigauge(app.GimbalTab, 'circular');
            app.CurrentRunGauge.Limits = [0 2];
            app.AnalysisDataPanel = uipanel(app.GimbalTab);
            app.AnalysisDataPanel.Title = 'Analysis Data';
            app.AnalysisListBox = uilistbox(app.AnalysisDataPanel);
            app.AnalysisListBox.Items = {};
            app.AnalysisListBox.Value = {};
            app.AnalysisDeleteButton = uibutton(app.AnalysisDataPanel, 'push');
            app.AnalysisDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisDeleteButtonPushed, true);
            app.AnalysisDeleteButton.Text = 'Delete';
            app.AnalysisClearButton = uibutton(app.AnalysisDataPanel, 'push');
            app.AnalysisClearButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisClearButtonPushed, true);
            app.AnalysisClearButton.Text = 'Clear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Meta_Terminal_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MetaTerminal)

            % Execute the startup function
            runStartupFcn(app, @Meta_Terminal_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MetaTerminal)
        end
    end
end